classdef browse_data_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        browse_data_fig            matlab.ui.Figure
        Series                     matlab.ui.control.Button
        TotalMBytesEditField       matlab.ui.control.NumericEditField
        TotalMbytesEditFieldLabel  matlab.ui.control.Label
        NbreFilesEditField         matlab.ui.control.NumericEditField
        NbredatafilesLabel         matlab.ui.control.Label
        ListOptions                matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        element_key_2              matlab.ui.control.Label
        SortDateButton             matlab.ui.control.StateButton
        SortNameButton             matlab.ui.control.StateButton
        SourceDir                  matlab.ui.control.EditField
        ListExperiments            matlab.ui.control.ListBox
        ListDevices                matlab.ui.control.ListBox
        DataSeries                 matlab.ui.control.Table
        Refresh                    matlab.ui.control.Button
        text14                     matlab.ui.control.Label
        text13                     matlab.ui.control.Label
        TotalFolderNbre            matlab.ui.control.EditField
        Down                       matlab.ui.control.Button
        Up                         matlab.ui.control.Button
        text9                      matlab.ui.control.Label
        OpenFile                   matlab.ui.control.Button
        element_key                matlab.ui.control.Label
        ContextMenuDataSeries      matlab.ui.container.ContextMenu
        RenameMenuDataSeries       matlab.ui.container.Menu
        DeleteMenuDataSeries       matlab.ui.container.Menu
        ContextMenuDevices         matlab.ui.container.ContextMenu
        RenameMenuDevices          matlab.ui.container.Menu
        DeleteMenuDevices          matlab.ui.container.Menu
    end


    methods (Access = public)
        %------------------------------------------------------------------
        % --- Executes when user attempts to close browse_data.TO CHECK
        function closefcn(app, gcbo, eventdata)
            %------------------------------------------------------------------
            hseries=findobj(allchild(0),'Tag','series');
            if ~isempty(hseries)
                hreplicate=findobj(hseries,'Tag','Replicate');
                set(hreplicate,'Value',0)
            end
            hcalib=findobj(allchild(0),'Tag','geometry_calib');
            if ~isempty(hcalib)
                hreplicate=findobj(hcalib,'Tag','Replicate');
                set(hreplicate,'Value',0)
            end
        end

        %------------------------------------------------------------------
        % ---  List the experiments in a campaign, filling the menu
        % ListExperiments NOT USED
        function errormsg = scan_campaign(app, SourceDir, Experiment)
            %------------------------------------------------------------------
            errormsg='';
            if ~isempty(regexp(SourceDir,'^http'))|| exist(SourceDir,'dir')
                ListStruct=dir_uvmat(SourceDir); %list files and dirs, extende to OpenDAP case
                if numel(ListStruct)>1000% A campaign folder must contain maily a list of 'experiment' sub-folders
                    errormsg=[SourceDir ' contains too many items (>1000) to be a Project folder'];
                    return
                end
                [ListFiles,index]=list_dir_1(app, SourceDir,Experiment);
                app.ListExperiments.Items=ListFiles;
                app.ListExperiments.Value=ListFiles(index);% initialise the menu selection with the folder defined by the input
                ListExperiments_Callback(app,[])
            else
                msgbox_uvmat('ERROR',['The input ' SourceDir ' is not a folder'])
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function  refresh_inputfile(app,InputName)
[DeviceRoot,Device,Ext]=fileparts(InputName);
            Device=[Device Ext];% will be selected for the column 'Device'
            
            [app.SourceDir.Value,Experiment,Ext]=fileparts(DeviceRoot);
            Experiment=[Experiment Ext];% will be selected for the column 'Experiment'

            [ListCells,~,~,errormsg] = scan_folder(app.SourceDir.Value,app.SortDateButton.Value,'short');
            app.ListExperiments.Items=ListCells(:,1);
            app.ListExperiments.Value=['+/' Experiment];

            [ListCells,~,~,errormsg] = scan_folder(DeviceRoot,app.SortDateButton.Value,'short');
            app.ListDevices.Items=ListCells(:,1);
            app.ListDevices.Value=['+/' Device];

            app.OpenFile.UserData='';% iitialise the output file name

             refresh_dataseries(app)
end


        function refresh_dataseries(app)
            %---Refresh the display of data series
         ExpName=regexprep(app.ListExperiments.Value,'^\+/','');
            if strcmp(ExpName,app.ListExperiments.Value)||isempty(app.ListDevices.Value) % not a folder, just a #files display
                app.DataSeries.Data=cell(1,3);
                return
            end 
            DeviceName=regexprep(app.ListDevices.Value,'^\+/','');
            if strcmp(DeviceName,app.ListDevices.Value)% not a folder, just a #files display
                if ~isempty(regexp(DeviceName,'.xml$', 'once'))% case of xml file
                    app.DataSeries.Data=cell(1,3);
                    return
                else
                DeviceName='';% list of data files to scan
                end
            end
            FullDirName=fullfile(app.SourceDir.Value,ExpName,DeviceName);% current folder to scan for updated content
            
            %% scan folder
            app.Refresh.BackgroundColor=[1 1 0];
            drawnow
            [DataCells,NbDir,NbXml,errormsg]=scan_folder(FullDirName,app.SortDateButton.Value,'long');
            if ~isempty(errormsg)
                disp(errormsg)
                app.Refresh.BackgroundColor=[0 1 0];
                return
            end
            if isempty(DataCells)
                app.DataSeries.Data={'empty folder','',''};
                addStyle(app.DataSeries,uistyle('FontColor','red'),'row',1)% color folders in red
                NbFiles=0;
            else
                if isempty(DeviceName)% just display the file list
                    app.DataSeries.Data=DataCells(NbDir+NbXml+1:end,:);
                     NbFiles=size(app.DataSeries.Data,1);
                    addStyle(app.DataSeries,uistyle('FontColor','black'),'row',(1:NbFiles)')% color folders in blue
                else
                    app.DataSeries.Data=DataCells;
                    NbFiles=size(app.DataSeries.Data,1)-NbDir-NbXml;
                    addStyle(app.DataSeries,uistyle('FontColor','blue'),'row',(1:NbDir)')% color folders in blue
                    addStyle(app.DataSeries,uistyle('FontColor','red'),'row',(NbDir+1:NbDir+NbXml)')% color folders in blue
                    addStyle(app.DataSeries,uistyle('FontColor','black'),'row',(NbDir+NbXml+1:NbDir+NbXml+NbFiles)')% color folders in blue
                end
            end
            app.DataSeries.Selection=[];% suppress any a priori data selection
            app.NbreFilesEditField.Value=NbFiles;
            app.TotalMBytesEditField.Value=sum([DataCells{:,3}]);
            app.Refresh.BackgroundColor=[0 1 0];
        end
%------------------------------------------------------
        function [DataPath,DataName]=get_fulldataname(app)
        DataList=app.DataSeries.Data;
            DataIndex=app.DataSeries.Selection;
            if isempty(DataIndex)
                msgbox_uvmat('ERROR','Select a file in DataSeries')
                return
            end
     
                DataName=DataList{DataIndex,1};
              % if ~isempty(regexp(DataName,'^\+/', 'once')) %a folder has been selected
                if isempty(regexp(DataName,'^\+/', 'once'))%a file has been selected
                    ExperimentName=regexprep(app.ListExperiments.Value,'^\+/','');
                    if strcmp(ExperimentName,app.ListExperiments.Value)
                        ExperimentName='';%Experiment selected not a folder, skip in the hierarchy
                    end
                    DeviceName=regexprep(app.ListDevices.Value,'^\+/','');
                    if strcmp(DeviceName,app.ListDevices.Value)
                        DeviceName='';%device selected not a folder, skip in the hierarchy
                    end
                    DataPath=fullfile(app.SourceDir.Value,ExperimentName,DeviceName);
                end
        end
        %------------------------------------------------------------------------
        function update_experiments(app, ListExperiments, CampaignPath, MirrorPath)
            % --- Executes when the mirror is created or updated
            % TODO:  MOVE IN uvmat
            %------------------------------------------------------------------------
            DataSeries={};
            for iexp=1:numel(ListExperiments)
                if strcmp(ListExperiments{iexp}(1),'+')% if the item is a directory
                    ListExperiments{iexp}(1)=[];
                    ListStruct=dir(fullfile(CampaignPath,ListExperiments{iexp})); %list files and dir in the source experiment directory
                    ListCells=struct2cell(ListStruct);%transform dir struct to a cell arrray
                    ListFiles=ListCells(1,:);%list of dir and file  names
                    cell_remove=regexp(ListFiles,'^((-|\.|\+/\.))');% detect strings beginning by '-' ,'.' or '+/.'(dir beginning by . )
                    check_keep=cellfun('isempty', cell_remove);
                    index_isdir=find(strcmp('isdir',fieldnames(ListStruct)));
                    check_dir=cell2mat(ListCells(index_isdir,:));% =1 for directories, =0 for files
                    for ilist=1:numel(ListFiles)
                        if check_keep(ilist)% loop on eligible DataSeries folders
                            DataSeries=fullfile(CampaignPath,ListExperiments{iexp},ListFiles{ilist});%source folder
                            if ~isempty(MirrorPath)
                                mirror=fullfile(MirrorPath,ListExperiments{iexp},ListFiles{ilist});
                                if exist(mirror,'file')% if mirror already exists as a file or folder
                                    [tild,msg]=fileattrib(mirror);
                                    if strcmp(msg.Name,mirror)%if the mirror name already exists as a local file or dir
                                        if msg.directory% case of a folder
                                            answer=msgbox_uvmat('INPUT_Y-N',['replace local folder ' msg.Name ' by a link to the source dir']);
                                            if strcmp(answer,'Yes')
                                                [ss,msg]=rmdir(mirror);
                                                if ss==1
                                                    system(['ln -s ' DataSeries ' ' mirror]); % create the link to the source folder
                                                else
                                                    msgbox_uvmat('ERROR',['enable to delete local folder: ' msg]);
                                                end
                                            end
                                        else % case of an existing mirror file
                                            answer=msgbox_uvmat('INPUT_Y-N',['replace local file ' msg.Name ' by a link to the source file']);
                                            if strcmp(answer,'Yes')
                                                delete(mirror);
                                                system(['ln -s ' DataSeries ' ' mirror]); % create the link to the source folder
                                            end
                                        end
                                    end
                                else% create mirror to the data series if needed
                                    system(['ln -s ' DataSeries ' ' mirror]); % create the link to the source folder
                                end
                                if isempty(find(strcmp(ListFiles{ilist},DataSeries), 1))% if the item is not already in DataSeries
                                    if check_dir(ilist)
                                        ListFiles{ilist}=['+/' ListFiles{ilist}];%mark dir by '+' in the list
                                    end
                                    DataSeries=[DataSeries;ListFiles{ilist}]; %append the item to the list
                                end
                            end
                        end
                    end
                end
            end
            DataTab=cell2tab(DataSeries);
            app.DataSeries_old.Items=sort(DataTab);
        end

       
        %------------------------------------------------------------------------
        function create_mirror_NOT_USED(app,InputFileName,FilterExt)
            % NOT USED,  TODO: MOVE to GUI uvmat menu Tools
            %------------------------------------------------------------------------
            set(handles.SourceDir,'BackgroundColor',[1 1 0])% indicate action of button by yellow color
            drawnow
            SourcePath=get(handles.SourceDir,'String');
            if ~isempty(regexp(SourcePath,'^http'))
                SourcePath=pwd;
                SourcePath=regexprep(SourcePath,'^\/.fsnet','/fsnet');
            end


            ProjectList=get(handles.ListExperiments,'String');
            ProjectName=ProjectList{get(handles.ListExperiments,'Value')};
            ProjectName=regexprep(ProjectName,'^\+/','');
            if strcmp(get(handles.MirrorDir,'Visible'),'on')
                MirrorDir=get(handles.MirrorDir,'String');% name of the mirror folder
            else% create the mirror folder if it does not exist
                MirrorRoot=uigetfile_uvmat('select the folder which must contain the mirror directory:',SourcePath,'uigetdir');
                if isempty(MirrorRoot)
                    return
                elseif strcmp(MirrorRoot,SourcePath)
                    msgbox_uvmat('ERROR','The mirror folder must be different from the source')
                    return
                else
                    MirrorDir=fullfile(MirrorRoot,ProjectName);
                end
                if exist(MirrorDir,'dir')
                    msgbox_uvmat('ERROR',['The folder ' MirrorDir ' chosen as new mirror campaign already exists'])
                    return
                else
                    [s,errormsg]=mkdir(MirrorDir);% create the mirror dir
                    if s~=1
                        msgbox_uvmat('ERROR',['error in creating ' MirrorDir ': ' errormsg])
                        return
                    end
                end
                SourceDir=fullfile(SourcePath,ProjectName);
                MirrorDoc.SourceDir=SourceDir;
                t=struct2xml(MirrorDoc);
                set(t,1,'name','DataTree');
                save(t,fullfile(MirrorDir,[ProjectName '.xml']))% create an xml file in the mirror folder to indicate its source folder
                set(handles.MirrorDir,'String',MirrorDir)
                set(handles.MirrorDir,'Visible','on')
                set(handles.CreateMirror,'String','update_mirror')
            end
            ExpName={''};

            %% update the mirror from the source dir
            if exist(SourceDir,'dir')
                hdir=dir(SourceDir); %list files and dirs
                idir=0;
                for ilist=1:length(hdir)
                    if hdir(ilist).isdir% scan all subfolders
                        dirname=hdir(ilist).name;%
                        if ~isequal(dirname(1),'.')&&~isequal(dirname(1),'0')%skip subfolder beginning by '0'
                            idir=idir+1;
                            mirror=fullfile(MirrorDir,hdir(ilist).name);% corresponding name in the mirror
                            if ~exist(mirror,'dir')
                                mkdir(mirror)% create the mirror folder if it does not exist
                            end
                            ExpName{idir}=['+/' hdir(ilist).name];% insert '+/' in the list to show that it is a folder
                        end
                        % look for the list of 'devices'
                    else
                        %warning for isolated files
                    end
                end
                set(handles.ListExperiments,'String',[{'*'};ExpName'])
                set(handles.ListExperiments,'Value',1)
                update_experiments(app,[{'*'};ExpName'],SourceDir,MirrorDir)% list the content of the experiment
            else
                msgbox_uvmat('ERROR',['The input ' SourceDir ' is not a directory'])
            end
            set(handles.SourceDir,'BackgroundColor',[1 1 1])
        end  

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function browse_data_OpeningFcn(app, InputName, scan_option)

            % Ensure that the app appears on screen when run
            movegui(app.browse_data_fig, 'onscreen');

            %% Determine the position of the dialog - centered on the upper right of the screen
            ScreenSize=get(0,'ScreenSize');
            app.browse_data_fig.Position(1)=ScreenSize(3)-800;
             app.browse_data_fig.Position(2)=ScreenSize(4)-900;    

            %% initialize the GUI options
            if ~exist('InputName','var')
                InputName=pwd;% open the current working folder by default
            end
            if isempty(regexp(InputName,'^http:', 'once')) && ischar(InputName) && ~exist(InputName,'file')
                InputName=pwd;% current dir is the starting data series by default
            end
            if ~exist('scan_option','var')
                scan_option='pick data'; % default option, the calling GUI is 'series' , or no calling GUI 
            end
            CheckMultiselect=~isempty(regexp(scan_option,'^scan '));
            app.ListExperiments.Multiselect=CheckMultiselect;
            app.ListDevices.Multiselect=CheckMultiselect;
            app.DataSeries.Multiselect=CheckMultiselect;
            app.Down.Visible=true2on(CheckMultiselect); %visible in case of scan option
            app.ListOptions.Value=scan_option;

            %% Initialise the table inputs
            refresh_inputfile(app,InputName)
            
        end

        % Callback function
        function CampaignDoc_Callback(app, event)
            % NOT USED: display the structure of the data tree in the form of an xml file
            %------------------------------------------------------------------------

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            answer=msgbox_uvmat('INPUT_Y-N','This function will update the global xml representation of the data set and the Heading of each xml file');
            if ~isequal(answer{1},'OK')
                return
            end
            set(handles.ListExperiments,'Value',1)
            ListExperiments_Callback(hObject, eventdata, handles)%update the overview of the experiment directories
            DataviewData=get(handles.browse_data,'UserData');
            List=DataviewData.List;
            Currentpath=get(handles.SourceDir,'String');
            [Currentpath,Campaign,DirExt]=fileparts(Currentpath);
            Campaign=[Campaign DirExt];
            t=xmltree;
            t=set(t,1,'name','CampaignDoc');
            t = attributes(t,'add',1,'source','directory');
            SubCampaignTest=get(handles.SubCampaignTest,'Value');
            root_uid=1;
            if SubCampaignTest
                %TO DO open an exoiting xml doc
                [t,root_uid]=add(t,1,'element','SubCampaign');
                t =attributes(t,'add',root_uid,'DirName',Campaign);
            end
            for iexp=1:length(List.Experiment)
                set(handles.ListExperiments,'Value',iexp+1)
                drawnow
                test_mod=0;
                [t,uid_exp]=add(t,root_uid,'element','Experiment');
                t = attributes(t,'add',uid_exp,'i',num2str(iexp));
                ExpName=List.Experiment{iexp}.name;
                t = attributes(t,'add',uid_exp,'DirName',List.Experiment{iexp}.name);

                if isfield(List.Experiment{iexp},'Device')
                    for idevice=1:length(List.Experiment{iexp}.Device)
                        [t,uid_device]=add(t,uid_exp,'element','Device');
                        DeviceName=List.Experiment{iexp}.Device{idevice}.name;
                        t = attributes(t,'add',uid_device,'DirName',List.Experiment{iexp}.Device{idevice}.name);
                        if isfield(List.Experiment{iexp}.Device{idevice},'xmlfile')
                            for ixml=1:length(List.Experiment{iexp}.Device{idevice}.xmlfile)
                                FileName=List.Experiment{iexp}.Device{idevice}.xmlfile{ixml};
                                [Title,test]=check_heading(Currentpath,Campaign,ExpName,DeviceName,[],FileName,SubCampaignTest);
                                if test
                                    disp([List.Experiment{iexp}.Device{idevice}.xmlfile{ixml} ' , Heading updated'])
                                end
                                if isequal(Title,'ImaDoc')
                                    [t,uid_xml]=add(t,uid_device,'element','ImaDoc');
                                    t = attributes(t,'add',uid_xml,'source','file');
                                    [t]=add(t,uid_xml,'chardata',List.Experiment{iexp}.Device{idevice}.xmlfile{ixml});
                                end
                            end
                        elseif isfield(List.Experiment{iexp}.Device{idevice},'Record')
                            for irecord=1:length(List.Experiment{iexp}.Device{idevice}.Record)
                                RecordName=List.Experiment{iexp}.Device{idevice}.Record{irecord}.name;
                                [t,uid_record]=add(t,uid_device,'element','Record');
                                t = attributes(t,'add',uid_record,'DirName',RecordName);
                                if isfield(List.Experiment{iexp}.Device{idevice}.Record{irecord},'xmlfile')
                                    for ixml=1:length(List.Experiment{iexp}.Device{idevice}.Record{irecord}.xmlfile)
                                        FileName=List.Experiment{iexp}.Device{idevice}.Record{irecord}.xmlfile{ixml};
                                        [Title,test]=check_heading(Currentpath,Campaign,ExpName,DeviceName,RecordName,FileName,SubCampaignTest);
                                        if test
                                            disp([FileName ' , Heading updated'])
                                        end
                                        [t,uid_xml]=add(t,uid_record,'element','ImaDoc');
                                        t = attributes(t,'add',uid_xml,'source','file');
                                        [t]=add(t,uid_xml,'chardata',FileName);
                                    end
                                end
                            end
                        end
                    end
                end
            end
            set(handles.ListExperiments,'Value',1)
            outputdir=get(handles.SourceDir,'String');
            [path,dirname]=fileparts(outputdir);
            outputfile=fullfile(outputdir,[dirname '.xml']);
            save(t,outputfile)
        end

        % Button pushed function: Down
        function Down_Callback(app, event)
           ExperimentName=regexprep(app.ListExperiments.Value,'^\+/','');
           app.SourceDir.Value=fullfile(app.SourceDir.Value,ExperimentName{1});
           app.ListExperiments.Items=app.ListDevices.Items;
           app.ListExperiments.Value=app.ListDevices.Value;
        end

        % Value changed function: ListDevices
        function ListDevices_Callback(app, event)
            ExperimentName=regexprep(app.ListExperiments.Value,'^\+/','');
            ExperimentFullName=fullfile(app.SourceDir.Value,ExperimentName);
            Device=app.ListDevices.Value;
            RefreshButtonPushed(app, [])

        end

        % Value changed function: ListExperiments
        function ListExperiments_Callback(app, event)
            ExpName=regexprep(app.ListExperiments.Value,'^\+/','');
            if strcmp(ExpName,app.ListExperiments.Value)% not a folder, # files display
                return
            end
            ExperimentFullName=fullfile(app.SourceDir.Value,ExpName);
            DeviceName=app.ListDevices.Value;%record the current device name
            [ListCells,~,~,errormsg] = scan_folder(ExperimentFullName,app.SortDateButton.Value,'short');
            if isempty(ListCells)
                app.ListDevices.Items={'empty folder'};
                app.ListDevices.Value='empty folder';
            else
                app.ListDevices.Items=ListCells(:,1);
                if isempty(DeviceName)|| isempty(find(strcmp(DeviceName,ListCells(:,1)), 1))
                    app.ListDevices.Value={};% if the previous Device name does not exist anymore, select nothing by default
                end
            end
             refresh_dataseries(app)
%             RefreshButtonPushed(app,[])
        end

        % Value changed function: SourceDir
        function SourceDir_Callback(app, event)
            [ListCells,~,~,errormsg] = scan_folder(app.SourceDir.Value,app.SortDateButton.Value,'short');
            if ~isempty(errormsg)
                msgbox_uvmat('ERROR',errormsg)
                return
            end
            app.ListExperiments.Items=ListCells(:,1);
            app.ListExperiments.Value=ListCells{1,1};% first line selected by defau
            app.ListDevices.Items={};
            app.DataSeries.Data=cell(1,3);

        end

        % Button pushed function: Up
        function Up_Callback(app, event)

            [app.SourceDir.Value,Experiment,Ext]=fileparts(app.SourceDir.Value);%move up in the data tree, [Exp ExpExt] is the name of the experiment
            Experiment=[Experiment Ext];
            if isempty(Experiment)
                msgbox_uvmat('ERROR','already at the root folder')
                return
            end

            app.ListDevices.Items=app.ListExperiments.Items;%transfer list of experiments to devices
            app.ListDevices.Value=app.ListExperiments.Value;%transfer selected list of experiments to devices

            [ListCells,~,~,errormsg] = scan_folder(app.SourceDir.Value,app.SortDateButton.Value,'short');
            app.ListExperiments.Items=ListCells(:,1);
            app.ListExperiments.Value=['+/' Experiment];

            RefreshButtonPushed(app,[])

        end

        % Button pushed function: OpenFile
        function OpenFileCallback(app, event)
            DataList=app.DataSeries.Data;
            DataIndex=app.DataSeries.Selection;
            if isempty(DataIndex)
                msgbox_uvmat('ERROR','Select a file in DataSeries')
                return
            end
            app.OpenFile.BackgroundColor=[1 1 0];%mark activity in yellow
            drawnow
            DataName=DataList{DataIndex,1};

            if isempty(regexp(DataName,'^\+/', 'once'))%a file has been selected
                ExperimentName=regexprep(app.ListExperiments.Value,'^\+/','');
                if strcmp(ExperimentName,app.ListExperiments.Value)
                    ExperimentName='';%Experiment selected not a folder, skip in the hierarchy
                end
                DeviceName=regexprep(app.ListDevices.Value,'^\+/','');
                if strcmp(DeviceName,app.ListDevices.Value)
                    DeviceName='';%device selected not a folder, skip in the hierarchy
                end
                FullDataName=fullfile(app.SourceDir.Value,ExperimentName,DeviceName,DataName);
                FileInfo=get_file_info(FullDataName);
                if strcmp(FileInfo.FileType,'txt')% ordinary text, just open it in the Matlab editor
                    edit(FullDataName)
                    msgbox_uvmat('CONFIRMATION',[DataName '  opened in the Matlab editor']); %brings the Matlab command window to the frontDataNameDataName
                elseif strcmp(FileInfo.FileType,'figure')% matlab figure, just open it
                    open(FullDataName)
                elseif strcmp(FileInfo.FileType,'xml')
%                     switch app.ListOptions.Value
%                         case {'pick xml config','pick xml object'}
%                             app.OpenFile.UserData=FullDataName;
%                             app.browse_data_fig.Tag='DisplayFile';%allow the calling program to resume
%                         otherwise
                            editxml(FullDataName);
%                     end
                else % open with uvmat
                    app.OpenFile.UserData=FullDataName;
                    huvmat=findobj(allchild(0),'Name','uvmat');
                    if isempty(huvmat)
                        uvmat(FullDataName);
                    else
                        hhuvmat=guidata(huvmat);
                        uvmat('MenuBrowse_Callback',hhuvmat.MenuBrowse,FullDataName,hhuvmat)
                        app.browse_data_fig.Tag='DisplayFile';
                        uistack(huvmat,'top')
                    end
                    
                end
            end
            app.OpenFile.BackgroundColor=[0 1 0];%mark end of activity
        end

        % Cell selection callback: DataSeries
        function DataSeriesCallback(app, event)

            %% replace 'ListDevices' by the selected 'DataSeries
            DataCells=app.DataSeries.Data;
            if isempty(DataCells)
                return
            end
            % Selection=1;% %default selection in the list
            if ~isempty(app.DataSeries.Selection)
                Selection=app.DataSeries.Selection;
            end
            if isempty(Selection) ||isempty(regexp(DataCells{Selection,1},'^\+/'))% not a folder
                return % no action
            else
                %% move the first selected 'Experiment' to SourceDir
                if ischar(app.ListExperiments.Value)
                    ExpName=app.ListExperiments.Value;
                else
                    ExpName=app.ListExperiments.Value{1};% select the first item in the list Experiments
                end
                ExpName=regexprep(ExpName,'^\+/','');% remove '+/' at the beginning
                app.SourceDir.Value=fullfile(app.SourceDir.Value,ExpName);  % update source path with the first selected experiment

                %% update 'ListExperiments' and transfer the selected 'Device'
                [ListCells,~,~,errormsg] = scan_folder(app.SourceDir.Value,app.SortDateButton.Value,'short');
                app.ListExperiments.Items=ListCells(:,1);%update the list of experiments
                app.ListExperiments.Value=app.ListDevices.Value; %  replace the selected 'ListExperiments' by the selected 'Devices'

                %% update 'ListDevices'and transfer the selected DataCell
                ExperimentName=regexprep(app.ListExperiments.Value,'^\+/','');
                ExperimentFullName=fullfile(app.SourceDir.Value,ExperimentName);
                [ListCells,~,~,errormsg] = scan_folder(ExperimentFullName,app.SortDateButton.Value,'short');
                app.ListDevices.Items=ListCells(:,1);
                app.ListDevices.Value=DataCells{Selection,1};

                %% update DataSeries
                RefreshButtonPushed(app,[])
            end
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            %% update the list of content for the 
             PrevExperiment=app.ListExperiments.Value; %TO UPDATE
             PrevDevice=app.ListDevices.Value;
             PrevData=app.DataSeries.Data;
             [ListCells,~,~,errormsg] = scan_folder(app.SourceDir.Value,app.SortDateButton.Value,'short');
             app.ListExperiments.Items=ListCells(:,1);
            % CheckExp=strcmp(PrevExperiment,app.ListExperiments.Items);
            %if ~isempty(app.ListExperiments.Value)
                DeviceRoot=fullfile(app.SourceDir.Value,regexprep(PrevExperiment,'^\+/',''));      
              [ListCells,~,~,errormsg] = scan_folder(DeviceRoot,app.SortDateButton.Value,'short');
             app.ListDevices.Items=ListCells(:,1);         
            
            refresh_dataseries(app)
           
        end

        % Value changed function: SortNameButton
        function SortNameButtonValueChanged(app, event)
            if app.SortNameButton.Value
                app.SortNameButton.BackgroundColor=[1 1 0];% indicate activation by yellow color
                app.SortDateButton.Value=false;% set sort date to false
                 app.SortDateButton.BackgroundColor=[1 1 1];
                app.SortDateButton.Text='sort date';

            else
                app.SortDateButton.Value=true;
                app.SortDateButton.BackgroundColor=[1 1 0];
                app.SortNameButton.BackgroundColor=[1 1 1];
         
            end
            drawnow
            RefreshButtonPushed(app, event)
              app.SortNameButton.BackgroundColor=[1 1 1];
              app.SortNameButton.Text='sorted by name';
        end

        % Value changed function: SortDateButton
        function SortDateButtonValueChanged(app, event)
             if app.SortDateButton.Value
                app.SortDateButton.BackgroundColor=[1 1 0];% indicate activation by yellow color
                app.SortNameButton.Value=false;
                 app.SortNameButton.BackgroundColor=[1 1 1];
                app.SortNameButton.Text='sort name';
            else
                 app.SortNameButton.Value=true;
                   app.SortNameButton.BackgroundColor=[1 1 0];
                  app.SortDateButton.BackgroundColor=[1 1 1];
             end
            drawnow
             RefreshButtonPushed(app, event)
             app.SortDateButton.BackgroundColor=[1 1 1];% activation finished, back to white color
              app.SortDateButton.Text='sorted by date';

        end

        % Callback function
        function browse_data_figSizeChanged(app, event)
            position = app.DataSeries.Position
            
        end

        % Button pushed function: Series
        function SeriesButtonPushed(app, event)
            DataList=app.DataSeries.Data;
            DataIndex=app.DataSeries.Selection;
            if isempty(DataIndex)
                msgbox_uvmat('ERROR','Select a file in DataSeries')
                return
            end
            app.Series.BackgroundColor=[1 1 0];%mark activity in yellow
            drawnow
            DataName=DataList{DataIndex,1};

            if isempty(regexp(DataName,'^\+/', 'once'))%a file has been selected
                ExperimentName=regexprep(app.ListExperiments.Value,'^\+/','');
                if strcmp(ExperimentName,app.ListExperiments.Value)
                    ExperimentName='';%Experiment selected not a folder, skip in the hierarchy
                end
                DeviceName=regexprep(app.ListDevices.Value,'^\+/','');
                if strcmp(DeviceName,app.ListDevices.Value)
                    DeviceName='';%device selected not a folder, skip in the hierarchy
                end
                FullDataName=fullfile(app.SourceDir.Value,ExperimentName,DeviceName,DataName);
                FileInfo=get_file_info(FullDataName);
                if ismember (FileInfo.FieldType,{'xml','image','mat','netcdf','civdata'})  
                    app.OpenFile.UserData=FullDataName;
                    hseries=findobj(allchild(0),'Name','series');
                    if ~isempty(hseries)
                        uistack(hseries,'top')
                        app.ListOptions.Value='pick data'; %back to default mode
                       % Param.InputFile=FullDataName;
                       % hhseries=guidata(hseries);
                        %series('display_file_name',hhseries,FullDataName,'append')
                        app.browse_data_fig.Tag='ProcessFile';% transfer activity to the GUI series
                    end

                end
            end
           % app.Series.BackgroundColor=[0 1 0];%mark end of activity
        end

        % Context menu opening function: ContextMenuDataSeries
        function ContextMenuDataSeriesOpening(app, event)


        end

        % Menu selected function: RenameMenuDataSeries, RenameMenuDevices
        function RenameMenuSelected(app, event)
            [DataPath,DataName]=get_fulldataname(app);
           answer= msgbox_uvmat('INPUT_TXT',['New name for ' DataName],DataName);
           if ~strcmp(answer,'Cancel')
           movefile(fullfile(DataPath,DataName),fullfile(DataPath,answer));
            refresh_dataseries(app)
           end
        end

        % Menu selected function: DeleteMenuDevices
        function DeleteMenuSelected(app, event)
            event
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.browse_data_fig = uifigure('Visible', 'off');
            app.browse_data_fig.Name = 'browse_data';
            app.browse_data_fig.Tag = 'browse_data_app';
            app.element_key = uilabel(app.browse_data_fig);
            app.element_key.Tag = 'element_key';
            app.element_key.Text = 'Devices';
            app.OpenFile = uibutton(app.browse_data_fig, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFileCallback, true);
            app.OpenFile.Tag = 'open_SubCampaign';
            app.OpenFile.Text = 'Open';
            app.text9 = uilabel(app.browse_data_fig);
            app.text9.Tag = 'text9';
            app.text9.Text = 'source path';
            app.Up = uibutton(app.browse_data_fig, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @Up_Callback, true);
            app.Up.Tag = 'Up';
            app.Up.Text = 'Up...';
            app.Down = uibutton(app.browse_data_fig, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @Down_Callback, true);
            app.Down.Tag = 'Down';
            app.Down.Visible = 'off';
            app.Down.Text = 'Down...';
            app.TotalFolderNbre = uieditfield(app.browse_data_fig, 'text');
            app.TotalFolderNbre.Tag = 'TotalFolderNbre';
            app.TotalFolderNbre.Visible = 'off';
            app.TotalFolderNbre.Tooltip = '''Mir'': name and path of the root mirror directory''';
            app.text13 = uilabel(app.browse_data_fig);
            app.text13.Tag = 'text13';
            app.text13.Visible = 'off';
            app.text13.Text = 'folders in total';
            app.text14 = uilabel(app.browse_data_fig);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Experiments';
            app.Refresh = uibutton(app.browse_data_fig, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Tag = 'Down';
            app.Refresh.Text = 'REFRESH';
            app.DataSeries = uitable(app.browse_data_fig);
            app.DataSeries.ColumnName = {'name'; 'date'; 'size(Mo)'};
            app.DataSeries.RowName = {};
            app.DataSeries.ColumnSortable = [true false true];
            app.DataSeries.SelectionType = 'row';
            app.DataSeries.CellSelectionCallback = createCallbackFcn(app, @DataSeriesCallback, true);
            app.ListDevices = uilistbox(app.browse_data_fig);
            app.ListDevices.Items = {'ListDevices'};
            app.ListDevices.Multiselect = 'on';
            app.ListDevices.ValueChangedFcn = createCallbackFcn(app, @ListDevices_Callback, true);
            app.ListDevices.Tag = 'ListDevices';
            app.ListDevices.Tooltip = '''ListDevices''';
            app.ListDevices.Value = {'ListDevices'};
            app.ListExperiments = uilistbox(app.browse_data_fig);
            app.ListExperiments.Items = {'ListExperiments'};
            app.ListExperiments.Multiselect = 'on';
            app.ListExperiments.ValueChangedFcn = createCallbackFcn(app, @ListExperiments_Callback, true);
            app.ListExperiments.Tag = 'ListExperiments';
            app.ListExperiments.Tooltip = '''ListExperiments''';
            app.ListExperiments.Value = {'ListExperiments'};
            app.SourceDir = uieditfield(app.browse_data_fig, 'text');
            app.SourceDir.ValueChangedFcn = createCallbackFcn(app, @SourceDir_Callback, true);
            app.SourceDir.Tag = 'SourceDir';
            app.SourceDir.Tooltip = '''SourceDir'': name and path of the root source directory ';
            app.SortNameButton = uibutton(app.browse_data_fig, 'state');
            app.SortNameButton.ValueChangedFcn = createCallbackFcn(app, @SortNameButtonValueChanged, true);
            app.SortNameButton.Text = 'sorted by name';
            app.SortNameButton.Value = true;
            app.SortDateButton = uibutton(app.browse_data_fig, 'state');
            app.SortDateButton.ValueChangedFcn = createCallbackFcn(app, @SortDateButtonValueChanged, true);
            app.SortDateButton.Text = 'sort date';
            app.element_key_2 = uilabel(app.browse_data_fig);
            app.element_key_2.Tag = 'element_key';
            app.element_key_2.Text = 'Data series';
            app.Label = uilabel(app.browse_data_fig);
            app.Label.Text = '';
            app.ListOptions = uieditfield(app.browse_data_fig, 'text');
            app.ListOptions.Editable = 'off';
            app.NbredatafilesLabel = uilabel(app.browse_data_fig);
            app.NbredatafilesLabel.Text = 'Nbre data files';
            app.NbreFilesEditField = uieditfield(app.browse_data_fig, 'numeric');
            app.NbreFilesEditField.Limits = [0 Inf];
            app.TotalMbytesEditFieldLabel = uilabel(app.browse_data_fig);
            app.TotalMbytesEditFieldLabel.Text = 'Total Mbytes';
            app.TotalMBytesEditField = uieditfield(app.browse_data_fig, 'numeric');
            app.TotalMBytesEditField.Limits = [0 Inf];
            app.Series = uibutton(app.browse_data_fig, 'push');
            app.Series.ButtonPushedFcn = createCallbackFcn(app, @SeriesButtonPushed, true);
            app.Series.Tag = 'open_SubCampaign';
            app.Series.Visible = 'off';
            app.Series.Text = 'Series';
            app.ContextMenuDataSeries = uicontextmenu(app.browse_data_fig);
            app.ContextMenuDataSeries.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuDataSeriesOpening, true);
            app.RenameMenuDataSeries = uimenu(app.ContextMenuDataSeries);
            app.RenameMenuDataSeries.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenuDataSeries.Text = 'Rename';
            app.DeleteMenuDataSeries = uimenu(app.ContextMenuDataSeries);
            app.DeleteMenuDataSeries.Text = 'Delete';
            % Assign app.ContextMenuDataSeries
            app.DataSeries.ContextMenu = app.ContextMenuDataSeries;
            app.ContextMenuDevices = uicontextmenu(app.browse_data_fig);
            app.RenameMenuDevices = uimenu(app.ContextMenuDevices);
            app.RenameMenuDevices.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenuDevices.Text = 'Rename';
            app.DeleteMenuDevices = uimenu(app.ContextMenuDevices);
            app.DeleteMenuDevices.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenuDevices.Text = 'Delete';
            % Assign app.ContextMenuDevices
            app.ListDevices.ContextMenu = app.ContextMenuDevices;
            % Show the figure after all components are created
            app.browse_data_fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = browse_data_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.browse_data_fig)

                % Execute the startup function
                runStartupFcn(app, @(app)browse_data_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.browse_data_fig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.browse_data_fig)
        end
    end
end